classdef CoefficientGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeradordecoeficientesUIFigure   matlab.ui.Figure
        Gauge                           matlab.ui.control.LinearGauge
        Label                           matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        ExecuteButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        RocketgeometryTab               matlab.ui.container.Tab
        NosetypeDropDownLabel           matlab.ui.control.Label
        NosetypeDropDown                matlab.ui.control.DropDown
        NosefinenessEditFieldLabel      matlab.ui.control.Label
        NosefinenessEditField           matlab.ui.control.NumericEditField
        FinwidthEditFieldLabel          matlab.ui.control.Label
        FinwidthEditField               matlab.ui.control.NumericEditField
        FinsemispanEditFieldLabel       matlab.ui.control.Label
        FinsemispanEditField            matlab.ui.control.NumericEditField
        StartingCGpositionmEditFieldLabel  matlab.ui.control.Label
        StartingCGpositionmEditField    matlab.ui.control.NumericEditField
        FinalCGpositionmEditFieldLabel  matlab.ui.control.Label
        FinalCGpositionmEditField       matlab.ui.control.NumericEditField
        RocketlengthmEditFieldLabel     matlab.ui.control.Label
        RocketlengthmEditField          matlab.ui.control.NumericEditField
        RocketdiametermEditFieldLabel   matlab.ui.control.Label
        RocketdiametermEditField        matlab.ui.control.NumericEditField
        SimulationconditionsTab         matlab.ui.container.Tab
        MaximumMachnumberSpinner        matlab.ui.control.Spinner
        MaximumMachnumberSpinnerLabel   matlab.ui.control.Label
        MinimumMachnumberSpinner        matlab.ui.control.Spinner
        MinimumMachnumberSpinnerLabel   matlab.ui.control.Label
        MaximumangleofattackSpinner     matlab.ui.control.Spinner
        MaximumangleofattackSpinnerLabel  matlab.ui.control.Label
        MinimumangleofattackSpinner     matlab.ui.control.Spinner
        MinimumangleofattackSpinnerLabel  matlab.ui.control.Label
        LaunchaltitudemEditField        matlab.ui.control.NumericEditField
        LaunchaltitudemEditFieldLabel   matlab.ui.control.Label
        MachnumberpointsSpinner         matlab.ui.control.Spinner
        MachnumberpointsSpinnerLabel    matlab.ui.control.Label
        AngleofattackpointsSpinner      matlab.ui.control.Spinner
        AngleofattackpointsSpinnerLabel  matlab.ui.control.Label
        SaveLoaddataTab                 matlab.ui.container.Tab
        LoadinputsButton                matlab.ui.control.Button
        FilenameEditField_2             matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        SaveinputsButton                matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        rocket % struct que descreve os parâmetros do foguete
        dados % struct que descreve as condições de simulação
        M %resultados de simulação
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NosetypeDropDown
        function NosetypeDropDownValueChanged(app, event)
            value = app.NosetypeDropDown.Value;
            if strcmp(value, 'Elipsoide')
                app.rocket.tipo_coifa = 1;
            elseif strcmp(value, 'Ogiva')
                app.rocket.tipo_coifa = 2;
            end
        end

        % Value changed function: NosefinenessEditField
        function NosefinenessEditFieldValueChanged(app, event)
            app.rocket.fineness = app.NosefinenessEditField.Value;
        end

        % Value changed function: FinwidthEditField
        function FinwidthEditFieldValueChanged(app, event)
            app.rocket.fin_width = app.FinwidthEditField.Value;
        end

        % Value changed function: FinsemispanEditField
        function FinsemispanEditFieldValueChanged(app, event)
            app.rocket.semispan = app.FinsemispanEditField.Value;
        end

        % Value changed function: StartingCGpositionmEditField
        function StartingCGpositionmEditFieldValueChanged(app, event)
            app.rocket.Lcg0 = app.StartingCGpositionmEditField.Value;
        end

        % Value changed function: FinalCGpositionmEditField
        function FinalCGpositionmEditFieldValueChanged(app, event)
            app.rocket.Lcgf = app.FinalCGpositionmEditField.Value;
        end

        % Value changed function: RocketlengthmEditField
        function RocketlengthmEditFieldValueChanged(app, event)
            app.rocket.L = app.RocketlengthmEditField.Value;
        end

        % Value changed function: RocketdiametermEditField
        function RocketdiametermEditFieldValueChanged(app, event)
            app.rocket.dref = app.RocketdiametermEditField.Value;
        end

        % Value changed function: MaximumMachnumberSpinner
        function MaximumMachnumberSpinnerValueChanged(app, event)
            app.dados.mach = linspace(app.MinimumMachnumberSpinner.Value, app.MaximumMachnumberSpinner.Value, app.MachnumberpointsSpinner.Value);
        end

        % Value changed function: MinimumMachnumberSpinner
        function MinimumMachnumberSpinnerValueChanged(app, event)
            app.dados.mach = linspace(app.MinimumMachnumberSpinner.Value, app.MaximumMachnumberSpinner.Value, app.MachnumberpointsSpinner.Value);
        end

        % Value changed function: MachnumberpointsSpinner
        function MachnumberpointsSpinnerValueChanged(app, event)
            app.dados.mach = linspace(app.MinimumMachnumberSpinner.Value, app.MaximumMachnumberSpinner.Value, app.MachnumberpointsSpinner.Value);
        end

        % Value changed function: MaximumangleofattackSpinner
        function MaximumangleofattackSpinnerValueChanged(app, event)
            alpha_vec = linspace(app.MinimumangleofattackSpinner.Value, app.MaximumangleofattackSpinner.Value, app.AngleofattackpointsSpinner.Value);
            %adiciona um zero se não estiver no vetor de alfas
            %TODO: fazer intervalo sempre simétrico, melhorar UI do alpha
            if all(0 ~= alpha_vec)
                alpha_vec = [alpha_vec(alpha_vec < 0) 0 alpha_vec(alpha_vec > 0)];
            end
            app.dados.alpha = alpha_vec;
        end

        % Value changed function: MinimumangleofattackSpinner
        function MinimumangleofattackSpinnerValueChanged(app, event)
            alpha_vec = linspace(app.MinimumangleofattackSpinner.Value, app.MaximumangleofattackSpinner.Value, app.AngleofattackpointsSpinner.Value);
            %adiciona um zero se não estiver no vetor de alfas
            %TODO: fazer intervalo sempre simétrico, melhorar UI do alpha
            if all(0 ~= alpha_vec)
                alpha_vec = [alpha_vec(alpha_vec < 0) 0 alpha_vec(alpha_vec > 0)];
            end
            app.dados.alpha = alpha_vec;
        end

        % Value changed function: AngleofattackpointsSpinner
        function AngleofattackpointsSpinnerValueChanged(app, event)
            alpha_vec = linspace(app.MinimumangleofattackSpinner.Value, app.MaximumangleofattackSpinner.Value, app.AngleofattackpointsSpinner.Value);
            %adiciona um zero se não estiver no vetor de alfas
            %TODO: fazer intervalo sempre simétrico, melhorar UI do alpha
            if all(0 ~= alpha_vec)
                alpha_vec = [alpha_vec(alpha_vec < 0) 0 alpha_vec(alpha_vec > 0)];
            end
            app.dados.alpha = alpha_vec;
        end

        % Value changed function: LaunchaltitudemEditField
        function LaunchaltitudemEditFieldValueChanged(app, event)
            app.dados.Alt0 = app.LaunchaltitudemEditField.Value;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            global M tempo
            % vetor de PHI que entra como "alternativa ao angulo BETA"
            % obs* no codigo do peixoto a configuracao eh phi+Alpha_Total
            % ao inves de "alpha+beta". Se quiserem mudar isso, mexer no simulink.
            app.dados.phif = 0:15:360; %o que isso faz?
            
            % decrescente!!!!
            app.dados.cg = linspace(app.rocket.Lcgf,app.rocket.Lcg0,5);
            
            % Construção do arquivo de entrada do DATCOM
            [for005] = for005_builder(app.rocket, app.dados);
            % RODAR O  DATCOM  PARA A MVO, EXTRAINDO OS DADOS DELE E SALVANDO.
            tic
            [M]=DATCOM_TO_MVO_automated(app.dados,for005, app);
            tempo = toc;
            disp(tempo)
            app.M = M %melhorar armazenamento de dados (guardar junto dos inputs? opção de ler só o resultado?)
            time = datestr(clock,'YYYY_mm_dd_HH_MM_SS');
            dados = app.dados;
            save(strcat('AED_TO_MVO_',time),'M','dados','for005')
        end

        % Button pushed function: SaveinputsButton
        function SaveinputsButtonPushed(app, event)
            rocket = app.rocket;    %o matlab não consegue salvar os dados com o prefixo
            dados = app.dados;
            save(app.FilenameEditField.Value, 'rocket', 'dados')
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            savefigs = true;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                   Graph 1                        %
            %               Plotting CD vs AoA                 %
            %                                                  %  
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figure;
            zero = find(app.dados.alpha == 0);
            interesting_alphas = app.dados.alpha(zero:end);
            
            phi_0 = 1; % Pegando phi = 0
            cg_cheio = size(app.dados.cg, 2);  % Pegando CG cheio
            
            hold on
            for k = 1:size(app.dados.mach,2)
                txt = ['M = ', num2str(app.dados.mach(k))];
                plot(interesting_alphas, reshape(app.M.CD(phi_0, k, zero:end, cg_cheio), size(app.dados.alpha(zero:end), 2), 1), 'DisplayName', txt)
            end
            hold off
            title('$C_D$ vs AoA (100\% Fuel)', 'Interpreter','latex');
            ylabel('$C_D$', 'Interpreter','latex');
            xlabel('Angle of Attack (Degrees)', 'Interpreter','latex');
            legend show
            legend('Location', 'northwest')
            
            if savefigs
                file = ['Graphics/CDvsAoA', datestr(clock,'YYYY_mm_dd'), '.png'];
                saveas(gcf, file);
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                   Graph 2                        %
            %               Plotting CD vs Mach                %
            %                                                  %  
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h2 = figure;
            zero = find(app.dados.alpha == 0);
            interesting_alphas = app.dados.alpha(zero:end);
            
            phi_0 = 1;
            cg_cheio = size(app.dados.cg, 2);
            
            hold on
            for k = 1:2:size(interesting_alphas,2)
                txt = ['$\alpha$ = ', num2str(interesting_alphas(k))];
                plot(app.dados.mach, reshape(app.M.CD(phi_0, :, zero+k-1, cg_cheio), size(app.dados.mach, 2), 1), 'DisplayName', txt, 'LineWidth', 1)
            end
            hold off
            title('$C_D$ vs Mach (100\% Fuel)', 'Interpreter','latex');
            ylabel('$C_D$', 'Interpreter','latex');
            xlabel('Mach', 'Interpreter','latex');
            legend show
            legend('Location', 'northwest', 'Interpreter','latex')
            
            if savefigs
                file = ['Graphics/CDvsMACH', datestr(clock,'YYYY_mm_dd'), '.png'];
                saveas(gcf, file);
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                   Graph 3                         %
            %             Plotting Static Margin                %
            %   Obs: O Datcom considera a saída "XCP" direto    %
            % como a Margem estática. Para mais informações,    %
            % consultar a página 54 do manual do Datcom, versão %
            % de dezembro de 1999.                              %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figure;
            zero = find(app.dados.alpha == 0);
            interesting_alphas = app.dados.alpha(zero:end);
            
            phi_0 = 1;
            alpha_index = zero; % Pegando ângulo de ataque nulo
            
            hold on
            for k = 1:size(app.dados.cg,2)
                fuel_percent = (k - 1)*100/(size(app.dados.cg,2) - 1);
                txt = ['Fuel = ', num2str(fuel_percent), '\%'];
                plot(app.dados.mach, reshape(-app.M.XCP(phi_0, :, alpha_index, k), size(app.dados.mach, 2), 1), 'DisplayName', txt, 'LineWidth', 1)
            end
            hold off
            title('Static Margin vs Mach (100\% Fuel)', 'Interpreter','latex');
            ylabel('Static Margin', 'Interpreter','latex');
            xlabel('Mach', 'Interpreter','latex');
            launching_static_margin = -app.M.XCP(phi_0, 1, alpha_index, cg_cheio);
            txt = ['\leftarrow Static Margin at Launch = ', num2str(launching_static_margin)];
            text(app.dados.mach(1), launching_static_margin, txt)
            legend show
            legend('Location', 'northwest', 'Interpreter','latex')
            
            if savefigs
                file = ['Graphics/StaticMargin', datestr(clock,'YYYY_mm_dd'), '.png'];
                saveas(gcf, file);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                   Graph 4                         %
            %          Plotting Static Margin with Wind         %
            %   Obs: O Datcom considera a saída "XCP" direto    %
            % como a Margem estática. Para mais informações,    %
            % consultar a página 54 do manual do Datcom, versão %
            % de dezembro de 1999.                              %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            max_wind = 10; % Maximum wind speed analyzed 
            Lref = 0.152;  % Rocket's area of reference (6' = 0.152 m)
            launch_angle = 86; % Launch angle
            % Speed of sound
            T = 27;
            gamma = 1.4;
            R = 287;
            a = sqrt(gamma*R*(T+273.15));
            
            % Velocity vector
            v = app.dados.mach*a;
            ramp_v = v(1);
            
            launch_angle = deg2rad(90-launch_angle);
            wind_angle = deg2rad(90);
            
            iter_wind = 197;            % Wind discretization
            iter_vel = 14;              % Rocket velocity discretization
            
            min_v = ramp_v - 5;   % Min and max velocity values we are searching through
            max_v = ramp_v + 5;  
            mod_Vwind = linspace(0, max_wind, iter_wind);
            mod_Vrocket = linspace(min_v, max_v, iter_vel);
            
            % Variable initalization
            v_rocket = zeros(2, length(mod_Vrocket));
            v_wind = zeros(2, length(mod_Vrocket));
            v_total = zeros(2, length(mod_Vwind));
            cos_angle(length(mod_Vrocket), length(mod_Vwind)) = 0;
            angle_of_attack(length(mod_Vrocket), length(mod_Vwind)) = 0;
            alpha(length(mod_Vrocket)) = 0;
            static_margin(length(mod_Vrocket), length(mod_Vwind)) = 0;
            
            v_rocket(1,:) = mod_Vrocket*cos(launch_angle);
            v_rocket(2,:) = mod_Vrocket*sin(launch_angle);
            
            % Calculations
            
            for i = 1:length(mod_Vrocket)
                for j = 1:length(mod_Vwind)
                    v_wind(1,j) = mod_Vwind(j)*cos(wind_angle);
                    v_wind(2,j) = mod_Vwind(j)*sin(wind_angle);
            
                    v_total(1,j) = v_rocket(1,i) + v_wind(1,j);
                    v_total(2,j) = v_rocket(2,i) + v_wind(2,j);
            
                    cos_angle(i,j) = dot([v_rocket(1,i); v_rocket(2,i)], [v_total(1,j); v_total(2,j)])/(norm([v_total(1,j); v_total(2,j)])*norm([v_rocket(1,i); v_rocket(2,i)]));
                    angle_of_attack(i,j) = real(rad2deg(acos(cos_angle(i,j))));
                end
            end
            
            full_fuel = size(app.dados.cg, 2);
            phi_0 = 1;
            for i = 1:length(mod_Vrocket)
                f = csapi(mod_Vwind, angle_of_attack(i,:));
                XCP0 = -app.M.XCP(phi_0, 1, :, full_fuel);
                f2 = csapi(app.dados.alpha, XCP0);
                
                max_aoa = angle_of_attack(end);
                n_max = true;
            
                for j = 1:length(mod_Vwind)
                    alpha(j) = fnval(f, mod_Vwind(j));
                    static_margin(i, j) = fnval(f2, alpha(j));
                end
            end
            
            figure
            contourf(mod_Vwind, mod_Vrocket, static_margin)
            colorbar
            title('Launching Static Margin', 'Fontsize', 20, 'Interpreter','latex')
            xlabel('$V_{WIND}$ (m/s)', 'Fontsize', 12, 'Interpreter','latex')
            ylabel('$V_{ROCKET}$ (m/s)', 'Fontsize', 12, 'Interpreter','latex')
            
            if savefigs
                file = ['Graphics/StaticMarginWind', datestr(clock,'YYYY_mm_dd'), '.png'];
                saveas(gcf, file);
            end
        end

        % Button pushed function: LoadinputsButton
        function LoadinputsButtonPushed(app, event)
            data = load(app.FilenameEditField_2.Value);
            app.rocket = data.rocket;
            app.dados = data.dados;
            %pegar erros de campo não preenchido
            %preenche os campos
            if app.rocket.tipo_coifa == 1
                app.NosetypeDropDown.Value = 'Elipsoide';
            elseif app.rocket.tipo_coifa == 2
                app.NosetypeDropDown = 'Ogiva';
            end
            app.NosefinenessEditField.Value = app.rocket.fineness;
            app.FinwidthEditField.Value = app.rocket.fin_width;
            app.FinsemispanEditField.Value = app.rocket.semispan;
            app.StartingCGpositionmEditField.Value = app.rocket.Lcg0;
            app.FinalCGpositionmEditField.Value = app.rocket.Lcgf;
            app.RocketlengthmEditField.Value = app.rocket.L;
            app.RocketdiametermEditField.Value = app.rocket.dref;
            
            app.MaximumMachnumberSpinner.Value = max(app.dados.mach);
            app.MinimumMachnumberSpinner.Value = min(app.dados.mach);
            app.MaximumangleofattackSpinner.Value = max(app.dados.alpha);
            app.MinimumangleofattackSpinner.Value = min(app.dados.alpha);
            app.LaunchaltitudemEditField.Value = app.dados.Alt0;
            app.MachnumberpointsSpinner.Value = length(app.dados.mach);
            app.AngleofattackpointsSpinner.Value = length(app.dados.alpha);
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeradordecoeficientesUIFigure = uifigure('Visible', 'off');
            app.GeradordecoeficientesUIFigure.Name = 'Gerador de coeficientes';
            app.TabGroup = uitabgroup(app.GeradordecoeficientesUIFigure);
            app.RocketgeometryTab = uitab(app.TabGroup);
            app.RocketgeometryTab.Title = 'Rocket geometry';
            app.RocketdiametermEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.RocketdiametermEditField.ValueChangedFcn = createCallbackFcn(app, @RocketdiametermEditFieldValueChanged, true);
            app.RocketdiametermEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.RocketdiametermEditFieldLabel.Text = 'Rocket diameter (m)';
            app.RocketlengthmEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.RocketlengthmEditField.ValueChangedFcn = createCallbackFcn(app, @RocketlengthmEditFieldValueChanged, true);
            app.RocketlengthmEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.RocketlengthmEditFieldLabel.Text = 'Rocket length (m)';
            app.FinalCGpositionmEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.FinalCGpositionmEditField.ValueChangedFcn = createCallbackFcn(app, @FinalCGpositionmEditFieldValueChanged, true);
            app.FinalCGpositionmEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.FinalCGpositionmEditFieldLabel.Text = 'Final CG position (m)';
            app.StartingCGpositionmEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.StartingCGpositionmEditField.ValueChangedFcn = createCallbackFcn(app, @StartingCGpositionmEditFieldValueChanged, true);
            app.StartingCGpositionmEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.StartingCGpositionmEditFieldLabel.Text = 'Starting CG position (m)';
            app.FinsemispanEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.FinsemispanEditField.ValueChangedFcn = createCallbackFcn(app, @FinsemispanEditFieldValueChanged, true);
            app.FinsemispanEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.FinsemispanEditFieldLabel.Text = 'Fin semispan';
            app.FinwidthEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.FinwidthEditField.ValueChangedFcn = createCallbackFcn(app, @FinwidthEditFieldValueChanged, true);
            app.FinwidthEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.FinwidthEditFieldLabel.Text = 'Fin width';
            app.NosefinenessEditField = uieditfield(app.RocketgeometryTab, 'numeric');
            app.NosefinenessEditField.ValueChangedFcn = createCallbackFcn(app, @NosefinenessEditFieldValueChanged, true);
            app.NosefinenessEditFieldLabel = uilabel(app.RocketgeometryTab);
            app.NosefinenessEditFieldLabel.Text = 'Nose fineness';
            app.NosetypeDropDown = uidropdown(app.RocketgeometryTab);
            app.NosetypeDropDown.Items = {'Selecione', 'Elipsoide', 'Ogiva'};
            app.NosetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @NosetypeDropDownValueChanged, true);
            app.NosetypeDropDown.Value = 'Elipsoide';
            app.NosetypeDropDownLabel = uilabel(app.RocketgeometryTab);
            app.NosetypeDropDownLabel.Text = 'Nose type';
            app.SimulationconditionsTab = uitab(app.TabGroup);
            app.SimulationconditionsTab.Title = 'Simulation conditions';
            app.AngleofattackpointsSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.AngleofattackpointsSpinnerLabel.Text = 'Angle of attack points';
            app.AngleofattackpointsSpinner = uispinner(app.SimulationconditionsTab);
            app.AngleofattackpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleofattackpointsSpinnerValueChanged, true);
            app.MachnumberpointsSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.MachnumberpointsSpinnerLabel.Text = 'Mach number points';
            app.MachnumberpointsSpinner = uispinner(app.SimulationconditionsTab);
            app.MachnumberpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @MachnumberpointsSpinnerValueChanged, true);
            app.LaunchaltitudemEditFieldLabel = uilabel(app.SimulationconditionsTab);
            app.LaunchaltitudemEditFieldLabel.Text = 'Launch altitude (m)';
            app.LaunchaltitudemEditField = uieditfield(app.SimulationconditionsTab, 'numeric');
            app.LaunchaltitudemEditField.ValueChangedFcn = createCallbackFcn(app, @LaunchaltitudemEditFieldValueChanged, true);
            app.MinimumangleofattackSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.MinimumangleofattackSpinnerLabel.Text = 'Minimum angle of attack';
            app.MinimumangleofattackSpinner = uispinner(app.SimulationconditionsTab);
            app.MinimumangleofattackSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumangleofattackSpinnerValueChanged, true);
            app.MaximumangleofattackSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.MaximumangleofattackSpinnerLabel.Text = 'Maximum angle of attack';
            app.MaximumangleofattackSpinner = uispinner(app.SimulationconditionsTab);
            app.MaximumangleofattackSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumangleofattackSpinnerValueChanged, true);
            app.MinimumMachnumberSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.MinimumMachnumberSpinnerLabel.Text = 'Minimum Mach number';
            app.MinimumMachnumberSpinner = uispinner(app.SimulationconditionsTab);
            app.MinimumMachnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumMachnumberSpinnerValueChanged, true);
            app.MaximumMachnumberSpinnerLabel = uilabel(app.SimulationconditionsTab);
            app.MaximumMachnumberSpinnerLabel.Text = 'Maximum Mach number';
            app.MaximumMachnumberSpinner = uispinner(app.SimulationconditionsTab);
            app.MaximumMachnumberSpinner.ValueChangedFcn = createCallbackFcn(app, @MaximumMachnumberSpinnerValueChanged, true);
            app.SaveLoaddataTab = uitab(app.TabGroup);
            app.SaveLoaddataTab.Title = 'Save/Load data';
            app.FilenameEditFieldLabel = uilabel(app.SaveLoaddataTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.SaveLoaddataTab, 'text');
            app.SaveinputsButton = uibutton(app.SaveLoaddataTab, 'push');
            app.SaveinputsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinputsButtonPushed, true);
            app.SaveinputsButton.Text = 'Save inputs';
            app.FilenameEditField_2Label = uilabel(app.SaveLoaddataTab);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.SaveLoaddataTab, 'text');
            app.LoadinputsButton = uibutton(app.SaveLoaddataTab, 'push');
            app.LoadinputsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadinputsButtonPushed, true);
            app.LoadinputsButton.Text = 'Load inputs';
            app.ExecuteButton = uibutton(app.GeradordecoeficientesUIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.PlotButton = uibutton(app.GeradordecoeficientesUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Label = uilabel(app.GeradordecoeficientesUIFigure);
            app.Label.Text = '%';
            app.Gauge = uigauge(app.GeradordecoeficientesUIFigure, 'linear');
            % Show the figure after all components are created
            app.GeradordecoeficientesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoefficientGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeradordecoeficientesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeradordecoeficientesUIFigure)
        end
    end
end